classdef CompundInterest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CompundInterestCalculatorybyMHasiburRahmanUIFigure  matlab.ui.Figure
        FinalDepositButton              matlab.ui.control.Button
        FinalDepositEditField           matlab.ui.control.NumericEditField
        TimeperiodKnob                  matlab.ui.control.DiscreteKnob
        TimeperiodKnobLabel             matlab.ui.control.Label
        YearsEditField                  matlab.ui.control.NumericEditField
        YearsEditFieldLabel             matlab.ui.control.Label
        NominalannualinterestrateEditField  matlab.ui.control.NumericEditField
        NominalannualinterestrateLabel  matlab.ui.control.Label
        InitialDepositEditField         matlab.ui.control.NumericEditField
        InitialDepositEditFieldLabel    matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: TimeperiodKnob
        function TimeperiodKnobValueChanged(app, event)
            % value = app.TimeperiodKnob.Value;
            
            % Comments:
            
            % This is an application that computes the compound interest
            % using formula A_t=A_0(1+r/n)^(nt)
            % It shows the final deposit value in the UI and also through
            % a barplot shows balance through the time interval (n * t)
        end

        % Button pushed function: FinalDepositButton
        function FinalDepositButtonPushed(app, event)
            if(app.TimeperiodKnob.Value == "Monthly")
                n = 12;
            end
            if(app.TimeperiodKnob.Value == "Annually")
                n = 1;
            end
            if(app.TimeperiodKnob.Value == "Weekly")
                n = 48;
            end
            if(app.TimeperiodKnob.Value == "Daily")
                n = 365;
            end

            % using formula A_t=A_0(1+r/n)^(nt)
            A = app.InitialDepositEditField.Value * (1 + (app.NominalannualinterestrateEditField.Value / n)).^(n * app.YearsEditField.Value);
            
            app.FinalDepositEditField.Value = A; % Adding result to FinalDeposit field
            
            % Barplot
            y = size(n * app.YearsEditField.Value); % y-axis vector declaring
            
            x = 1 : (n * app.YearsEditField.Value); % x-axis 

            for i = 1 : (n * app.YearsEditField.Value)
               % iterating formula for each time interval and storing it in a vector, y
               y(i) = app.InitialDepositEditField.Value * (1 + (app.NominalannualinterestrateEditField.Value / n)).^(i);
            
            end
            % plotting barplot
            bar(app.UIAxes, x, y, "green");

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CompundInterestCalculatorybyMHasiburRahmanUIFigure = uifigure('Visible', 'off');
            app.CompundInterestCalculatorybyMHasiburRahmanUIFigure.Name = 'Compund Interest Calculatory by M Hasibur Rahman';
            app.UIAxes = uiaxes(app.CompundInterestCalculatorybyMHasiburRahmanUIFigure);
            title(app.UIAxes, 'Compund Interest')
            xlabel(app.UIAxes, {'Time Intervals'; ''})
            ylabel(app.UIAxes, 'Balance ($)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0.2 0.4 0.6 0.8 1];
            app.InitialDepositEditFieldLabel = uilabel(app.CompundInterestCalculatorybyMHasiburRahmanUIFigure);
            app.InitialDepositEditFieldLabel.Text = 'Initial Deposit:';
            app.InitialDepositEditField = uieditfield(app.CompundInterestCalculatorybyMHasiburRahmanUIFigure, 'numeric');
            app.InitialDepositEditField.Value = 100;
            app.NominalannualinterestrateLabel = uilabel(app.CompundInterestCalculatorybyMHasiburRahmanUIFigure);
            app.NominalannualinterestrateLabel.Text = 'Nominal annual interest rate:';
            app.NominalannualinterestrateEditField = uieditfield(app.CompundInterestCalculatorybyMHasiburRahmanUIFigure, 'numeric');
            app.NominalannualinterestrateEditField.Value = 0.05;
            app.YearsEditFieldLabel = uilabel(app.CompundInterestCalculatorybyMHasiburRahmanUIFigure);
            app.YearsEditFieldLabel.Text = 'Years:';
            app.YearsEditField = uieditfield(app.CompundInterestCalculatorybyMHasiburRahmanUIFigure, 'numeric');
            app.YearsEditField.Value = 4;
            app.TimeperiodKnobLabel = uilabel(app.CompundInterestCalculatorybyMHasiburRahmanUIFigure);
            app.TimeperiodKnobLabel.Text = {'Time period'; ''};
            app.TimeperiodKnob = uiknob(app.CompundInterestCalculatorybyMHasiburRahmanUIFigure, 'discrete');
            app.TimeperiodKnob.Items = {'Annually', 'Monthly', 'Weekly', 'Daily'};
            app.TimeperiodKnob.ValueChangedFcn = createCallbackFcn(app, @TimeperiodKnobValueChanged, true);
            app.TimeperiodKnob.Value = 'Monthly';
            app.FinalDepositEditField = uieditfield(app.CompundInterestCalculatorybyMHasiburRahmanUIFigure, 'numeric');
            app.FinalDepositButton = uibutton(app.CompundInterestCalculatorybyMHasiburRahmanUIFigure, 'push');
            app.FinalDepositButton.ButtonPushedFcn = createCallbackFcn(app, @FinalDepositButtonPushed, true);
            app.FinalDepositButton.Text = {'Final Deposit'; ''};
            % Show the figure after all components are created
            app.CompundInterestCalculatorybyMHasiburRahmanUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CompundInterest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CompundInterestCalculatorybyMHasiburRahmanUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CompundInterestCalculatorybyMHasiburRahmanUIFigure)
        end
    end
end